classdef elastane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ELaStAnEUIFigure             matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        LoadButton                   matlab.ui.control.Button
        CurrentfileEditFieldLabel    matlab.ui.control.Label
        CurrentfileEditField         matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        ExperimentaldataPanel        matlab.ui.container.Panel
        JLEditFieldLabel             matlab.ui.control.Label
        JLEditField                  matlab.ui.control.NumericEditField
        JGEditFieldLabel             matlab.ui.control.Label
        JGEditField                  matlab.ui.control.NumericEditField
        LiquiddensityEditFieldLabel  matlab.ui.control.Label
        LiquiddensityEditField       matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel    matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.NumericEditField
        PressureEditFieldLabel       matlab.ui.control.Label
        PressureEditField            matlab.ui.control.NumericEditField
        AtmPressureEditFieldLabel    matlab.ui.control.Label
        AtmPressureEditField         matlab.ui.control.NumericEditField
        UIAxes_2                     matlab.ui.control.UIAxes
        Tree                         matlab.ui.container.Tree
        HistogramsTab                matlab.ui.container.Tab
        fig_Euler_3                  matlab.ui.control.UIAxes
        VariablesListBox_3Label      matlab.ui.control.Label
        VariablesListBox_3           matlab.ui.control.ListBox
        ExperimentalpointListBox_3Label  matlab.ui.control.Label
        ExpPoints_hist               matlab.ui.control.ListBox
        StationListBoxLabel          matlab.ui.control.Label
        Station_hist                 matlab.ui.control.ListBox
        EulerTab                     matlab.ui.container.Tab
        VariablesListBoxLabel        matlab.ui.control.Label
        VariablesListBox             matlab.ui.control.ListBox
        fig_Euler_4                  matlab.ui.control.UIAxes
        StatisticButtonGroup         matlab.ui.container.ButtonGroup
        AverageButton                matlab.ui.control.RadioButton
        StdDeviationButton           matlab.ui.control.RadioButton
        FittypeListBoxLabel          matlab.ui.control.Label
        FittypeListBox               matlab.ui.control.ListBox
        CustomfittypeEditFieldLabel  matlab.ui.control.Label
        CustomfittypeEditField       matlab.ui.control.EditField
        LagrangeTab                  matlab.ui.container.Tab
        fig_Euler_2                  matlab.ui.control.UIAxes
        VariablesListBox_2Label      matlab.ui.control.Label
        VariablesListBox_2           matlab.ui.control.ListBox
        ExperimentalpointListBox_2Label  matlab.ui.control.Label
        ExperimentalpointListBox_2   matlab.ui.control.ListBox
        StationsListBoxLabel         matlab.ui.control.Label
        StationsListBox              matlab.ui.control.ListBox
        ReadyLampLabel               matlab.ui.control.Label
        ReadyLamp                    matlab.ui.control.Lamp
        ELaStAnEbyConradoChiarello2020Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        lfile % Description
        result % Description
        euler % Description
        lagrange % Description
    end
    
    methods (Access = private)
        
        function plotflowmap(app, theta, D, rho_L, rho_G, jLpt, jGpt)
            
            % theta = 0;
            
            g = 9.81;
            
            % D = 1*25.4E-3;
            
            % rho_L = 1000;
            % rho_G = 1.23;
            
            delta_rho = rho_L - rho_G;
            
            mu_L = 8.9E-4;
            mu_G = 1.84E-5;
            
            C_L = 0.046;
            n = 0.2;
            
            C_G = 0.046;
            m = 0.2;
            
            pts = 30;
            j_L = logspace(-3, 1, pts);
            j_G = logspace(-2, 2, pts);
            
            j_L_SS = zeros(numel(j_L)*numel(j_G),1) + nan;
            j_L_SW = j_L_SS;
            j_L_A = j_L_SS;
            j_L_S = j_L_SS;
            j_L_DB = j_L_SS;
            
            j_G_SS = j_L_SS;
            j_G_SW = j_G_SS;
            j_G_A = j_G_SS;
            j_G_S = j_G_SS;
            j_G_DB = j_G_SS;
            
            h_L_t = 1e-3 : 1e-3 : 1 - 1e-3;
            k = 0;
            
            for i = 1 : numel(j_L)
                Re_SL = rho_L*j_L(i)*D/mu_L;
                dpdL_SL = 4*(C_L/D)*Re_SL^(-n)*0.5*rho_L*j_L(i)^2;
                for j = 1 : numel(j_G)
                    k = k + 1;
                    
                    Re_SG = rho_G*j_G(j)*D/mu_G;
                    dpdL_SG = 4*(C_G/D)*Re_SG^(-m)*0.5*rho_G*j_G(j)^2;
                    X = sqrt(dpdL_SL/dpdL_SG);
                    Y = delta_rho*g*sin(theta)/dpdL_SG;
                    
                    hh = 2*h_L_t - 1;
                    S_G_t = acos(hh);
                    S_L_t = pi - S_G_t;
                    S_I_t = sqrt(1 - hh.^2);
                    A_L_t = 0.25*(S_L_t + hh.*S_I_t);
                    A_G_t = 0.25*(S_G_t - hh.*S_I_t);
                    v_L_t = 0.25*pi./A_L_t;
                    v_G_t = 0.25*pi./A_G_t;
                    d_L_t = 4*A_L_t./S_L_t;
                    d_G_t = 4*A_G_t./(S_G_t + S_I_t);
                    
                    eqn = X^2*(v_L_t.*d_L_t).^(-n).*v_L_t.^2.*S_L_t./A_L_t - (v_G_t.*d_G_t).^(-m).*v_G_t.^2.*(S_G_t./A_G_t + S_I_t./A_L_t + S_I_t./A_G_t) + 4*Y;
                    
                    [~, idx] = min(abs(eqn));
                    h_L_n = h_L_t(idx);
                    %         h_L_n = a/(b*x^p+ c + d*x^l);
                    %                 h_L_n = (p1*x^4 + p2*x^3 + p3*x^2 + p4*x + p5)./(x^4 + q1*x^3 + q2*x^2 + q3*x + q4);
                    %         h_L = fzero(@findhl, 0.5);
                    A_L_t_n = 0.25.*(pi - acos(2.*h_L_n - 1) + (2.*h_L_n - 1).*sqrt(1 - (2.*h_L_n - 1).^2));
                    A_G_t_n = 0.25*(acos(2*h_L_n - 1) - (2*h_L_n - 1).*sqrt(1 - (2*h_L_n - 1).^2));
                    S_L_t_n = pi - acos(2*h_L_n - 1);
                    S_I_t_n = sqrt(1 - (2*h_L_n - 1).^2);
                    v_L_t_n = 0.25*pi/A_L_t_n;
                    v_G_t_n = 0.25*pi/A_G_t_n;
                    d_L_t_n = 4*A_L_t_n/S_L_t_n;
                    
                    % strat -> non-strat
                    % strat: ss -> sw
                    % non-strat: annular
                    % non-annular: interm -> db
                    F2 = rho_G/(delta_rho*g*D*cos(theta))*j_G(j)^2;
                    
                    % Transition A: strat to non-strat
                    
                    % Stratified
                    if F2*v_G_t_n*S_I_t_n/((1 - h_L_n)^2*A_G_t_n) < 1
                        
                        K = sqrt(F2*Re_SL);
                        if K >= 2/(sqrt(0.01*v_L_t_n)*v_G_t_n)
                            j_L_SW(k) = j_L(i);
                            j_G_SW(k) = j_G(j);
                            continue
                        else
                            j_L_SS(k) = j_L(i);
                            j_G_SS(k) = j_G(j);
                            continue
                        end
                        % Non-stratified
                    else
                        if h_L_n <= 0.35
                            j_L_A(k) = j_L(i);
                            j_G_A(k) = j_G(j);
                            continue
                        else
                            T2 = dpdL_SL/(delta_rho*g*cos(theta));
                            if T2 >= 8*A_G_t_n/(S_I_t_n*v_L_t_n^2*(v_L_t_n*d_L_t_n)^(-n))
                                j_G_DB(k) = j_G(j);
                                j_L_DB(k) = j_L(i);
                                continue
                            else
                                j_L_S(k) = j_L(i);
                                j_G_S(k) = j_G(j);
                                continue
                            end
                        end
                    end
                end
            end
            
            j_G_SS = j_G_SS(~isnan(j_G_SS));
            j_G_SW = j_G_SW(~isnan(j_G_SW));
            j_G_S = j_G_S(~isnan(j_G_S));
            j_G_DB = j_G_DB(~isnan(j_G_DB));
            j_G_A = j_G_A(~isnan(j_G_A));
            
            j_L_SS = j_L_SS(~isnan(j_L_SS));
            j_L_SW = j_L_SW(~isnan(j_L_SW));
            j_L_S = j_L_S(~isnan(j_L_S));
            j_L_DB = j_L_DB(~isnan(j_L_DB));
            j_L_A = j_L_A(~isnan(j_L_A));
            
            p = loglog(app.UIAxes_2, j_G_SS, j_L_SS, 'r+', j_G_SW, j_L_SW, 'b+', j_G_S, j_L_S, 'g+', j_G_DB, j_L_DB, 'm+', j_G_A, j_L_A, 'k+', jGpt, jLpt, 'k.');
            p(6).MarkerSize = 15;
        end
        function Vtb = vbend(~, jL, jG, tubeDiameter, inclinationAngle)
            
            g = 9.81;
            Fr_jL = jL^2/(g*tubeDiameter);
            
            j = jL + jG;
            
            C_0h = 1.05;
            C_0v = 1.20;
            
            if Fr_jL < 3.5
                C_0 = C_0h + (C_0v - C_0h)*sin(inclinationAngle)^2;
                C_inf = 0.54*cos(inclinationAngle) + 0.35*sin(inclinationAngle);
            else
                C_0 = C_0v;
                C_inf = 0.35*sin(inclinationAngle);
            end
            
            Vtb = C_0*j + C_inf*sqrt(g*tubeDiameter);
        end
        
        function [idx, start_hi] = ucdet(app, ts, D, jl, jg, fs, theta)
            [idx, start_hi, ehalf] = hilo3(app, ts);
            jl = mean(jl);
            jg = mean(jg);
            
            % j = jl + jg;
            
            v = vbend(app, jl, jg, D, theta);
            % v = 1.2*j + 0.54*sqrt(g*D);
            L = v*(idx(:,2) - idx(:,1))/fs;
            n = 0.5*numel(idx);
            idx = reshape(idx, [n, 2]);
            idx = fixlb1d(app, idx, L, D);
            idx = fixderivative(app, idx, ts);
            idx = fixvf(app, idx, ts, ehalf);
            n = 0.5*numel(idx);
            idx = reshape(idx, [n, 2]);
            idx = dfix(app, idx);
            
        end
        
        function [idx, start_hi, ehalf] = hilo3(~, ts)
            
            [N, e] = histcounts(ts);
            
            sz = numel(N);
            
            [~, max_idx_1] = max(N(1 : fix(0.5*sz)));
            half = fix(0.5*sz);
            
            ehalf = e(half);
            
            hi = e(fix(0.5*(half+max_idx_1+1)));
            % hi = 0.3;
            lo = e(max_idx_1 + 1);
            
            ts_hi = ts > hi;
            ts_lo = ts > lo;
            
            start_hi = find(diff(ts_hi) == 1);
            end_hi = find(diff(ts_hi) == -1);
            
            start_lo = find(diff(ts_lo) == 1);
            end_lo = find(diff(ts_lo) == -1) + 1;
            
            start_hi(start_hi < start_lo(1)) = [];
            end_hi(end_hi > end_lo(end)) = [];
            
            sz_start = numel(start_hi);
            sz_end = numel(end_hi);
            
            true_start_lo = zeros(size(start_hi));
            true_end_lo = zeros(size(end_hi));
            
            for i = 1 : sz_start
                true_start_lo(i) = start_lo(find(start_lo <= start_hi(i), 1, 'last'));
            end
            
            for i = 1 : sz_end
                true_end_lo(i) = end_lo(find(end_lo >= end_hi(i), 1));
            end
            
            true_start_lo = unique(true_start_lo);
            true_end_lo = unique(true_end_lo);
            
            while true_end_lo(1) <= true_start_lo(1)
                true_end_lo(1) = [];
            end
            
            while true_start_lo(end) >= true_end_lo(end)
                true_start_lo(end) = [];
            end
            
            if numel(true_start_lo) < numel(true_end_lo)
                true_end_lo(1) = [];
            elseif numel(true_end_lo) < numel(true_start_lo)
                true_start_lo(end) = [];
            end
            
            idx = [true_start_lo, true_end_lo];
            
        end
        
        function idx = fixlb1d(~, idx, L, D)
            idx(L < D, :) = [];
        end
        
        function idx = fixvf(~, idx, ts, ehalf)
            n = numel(idx(:,1));
            
            alpha = ehalf;
            
            for i = 1 : n
                sts = ts(idx(i,1) : idx(i,2));
                if mean(sts) < alpha
                    idx(i,:) = nan;
                end
            end
            idx = idx(~isnan(idx));
        end
        
        function idx = fixderivative(~, idx, ts)
            n = numel(idx(:,1));
            
            for i = 2 : n-1
                % Find new start based on derivative
                sts_s = ts(idx(i-1, 2) : idx(i, 1));
                fd = find(diff(sts_s) <= 0, 1, 'last');
                if ~isempty(fd)
                    idx(i,1) = idx(i-1, 2) + fd;
                end
                
                % Find new end based on derivative
                sts_e = ts(idx(i, 2) : idx(i+1, 1));
                fd = find(diff(sts_e) >= 0, 1, 'first');
                if ~isempty(fd)
                    idx(i,2) = idx(i,2) + fd - 1;
                end
            end
        end
        
        function idx = dfix(~, idx)
            
            istart = idx(:,1);
            iend = idx(:,2);
            
            if istart(1) > iend(1)
                iend(1) = [];
            end
            
            if istart(end) > iend(end)
                istart(end) = [];
            end
            
            i = 2;
            
            while 1


                if i > numel(istart) || i > numel(iend)
                    break
                end
                
                if iend(i) == istart(i)
                    iend(i) = [];
                    istart(i) = [];
                    continue
                end
                
                if istart(i) == iend(i - 1)
                    istart(i) = [];
                    iend(i - 1) = [];
                    continue
                end
                
                i = i + 1;
            end
            
            if numel(istart) > numel(iend)
                
                while numel(iend) < numel(istart)
                    istart(end) = [];
                end
                
            elseif numel(iend) > numel(istart)
                
                while numel(istart) < numel(iend)
                    iend(end) = [];
                end
                
            end
            
            idx = [istart, iend];
            
        end
        function VB = velocity_mc(~, idx, ts, jl, jg)
            
            jl = mean(jl);
            jg = mean(jg);
            j = jl + jg;
            
            N = numel(idx)/2;
            
            VB = zeros(N,1);
            
            for i = 1 : N-1
                
                ts_f = ts(idx(i,1) : idx(i,2));
                Nf = numel(ts_f);
                RGb = sum(ts_f);
                
                ts_s = ts(idx(i,2) : idx(i+1,1));
                Ns = numel(ts_s);
                RGs = mean(ts_s);
                
                Nu = Nf + Ns;
                
                VB(i) = Nu*(jl - j*(1 - RGs))/(RGs*Nf - RGb);
                
            end
            
            ts_f = ts(idx(N,1) : idx(N,2));
            Nf = numel(ts_f);
            RGb = sum(ts_f);
            ts_s = ts(idx(N,2) : end);
            Ns = numel(ts_s);
            RGs = mean(ts_s);
            
            Nu = Nf + Ns;
            
            VB(N) = Nu*(jl - j*(1-RGs))/(RGs*Nf - RGb);
        end
        function freq = frequency(~, idx, ts, fs)
            
            % Evaluate UC frequency
            n = numel(idx(:,1));
            
            freq = zeros(n,1);
            for i = 1 : n - 1
                freq(i) = idx(i+1,1) - idx(i,1);
            end
            % i = n
            freq(n) = numel(ts) - idx(end,1);
            
            freq = fs./freq;
        end
        
        function [Lb, Ls, RGb, RGs] = hparam(~, idx, v, ts, fs)
            
            n = numel(idx(:,1));
            
            Lb = zeros(n - 1,1);
            RGb = Lb;
            Ls = Lb;
            RGs = Lb;
            
            for i = 1 : n - 1
                RGb(i) = mean(ts(idx(i,1) : idx(i,2)));
                RGs(i) = mean(ts(idx(i,2) : idx(i+1, 1)));
            end
            
            Lb(1:n-1) = v(1:n-1).*(idx(1:n-1,2) - idx(1:n-1,1))/fs;
            Ls(1:n-1) = v(1:n-1).*(idx(2:n,1) - idx(1:n-1,2))/fs;
            
            % i = n
            
            Lb(n) = v(n)*(idx(n, 2) - idx(n, 1))/fs;
            Ls(n) = v(n)*(numel(ts) - idx(n, 2))/fs;
            RGb(n) = mean(ts(idx(n,1) : idx(n,2)));
            RGs(n) = mean(ts(idx(n,2) : end));
        end
        
        function result = dataprocess(app, st, pt)
            for j = 1:numel(pt)
                for i = 1:numel(st)
                    cst = st{i};
                    cpt = pt{j};
                    ts = app.lfile.data.(cst).(cpt).TS1;
                    jl = nanmean(app.lfile.data.(cst).(cpt).jl);
                    jg = nanmean(app.lfile.data.(cst).(cpt).jg);
                    D = app.lfile.data.(cst).Geometry.Diameter;
                    fs = app.lfile.data.(cst).Geometry.Frequency;
                    theta = app.lfile.data.(cst).Geometry.Angle;
                    
                    idx = ucdet(app, ts, D, jl, jg, fs, theta);
                    VB = velocity_mc(app, idx, ts, jl, jg);
                    VB(end) = [];
                    idx(end,:) = [];
                    freq = frequency(app, idx, ts, fs);
                    [lb, ls, rgb, rgs] = hparam(app, idx, VB, ts, fs);
                    beta = lb./(lb+ls);
                    neg = VB <= 0;
                    out = isoutlier(VB);
                    del = out | neg;
                    VB(del) = [];
                    lb(del) = [];
                    ls(del) = [];
                    rgb(del) = [];
                    rgs(del) = [];
                    beta(del) = [];
                    freq(del) = [];
                    
                    result.(cpt).(cst).VB = VB;
                    result.(cpt).(cst).lb = lb/D;
                    result.(cpt).(cst).ls = ls/D;
                    result.(cpt).(cst).rgb = rgb;
                    result.(cpt).(cst).rls = 1-rgs;
                    result.(cpt).(cst).freq = freq;
                    result.(cpt).(cst).beta = beta;
                end
            end
        end
        
        function plothistogram(app)
            pt = app.ExpPoints_hist.Value;
            st = app.Station_hist.Value;
            selectedVar = app.VariablesListBox_3.Value;
            
            switch selectedVar
                case 'Velocity'
                    var = 'VB';
                case 'Frequency'
                    var = 'freq';
                case 'Bubble Length'
                    var = 'lb';
                case 'Bubble Void Fraction'
                    var = 'rgb';
                case 'Liquid Slug Holdup'
                    var = 'rls';
                case 'Intermittence Factor'
                    var = 'beta';
            end
            
            res = app.result.(pt).(st{1}).(var);
            histogram(app.fig_Euler_3, res)
            
        end
        function ploteuler(app)
            selectedVar = app.VariablesListBox.Value;
            statvalue = app.AverageButton.Value;
            ftype = app.FittypeListBox.Value;
            
            if statvalue
                stat = 'mean';
            else
                stat = 'std';
            end
            
            switch selectedVar
                case 'Velocity'
                    vary = 'VB';
                    varx = 'j';
                    xlab = 'J [m/s]';
                    ylab = 'V_{TB} [m/s]';
                case 'Frequency'
                    vary = 'freq';
                    varx = 'jgjl';
                    xlab = 'J_G/J_L [-]';
                    ylab = 'f [Hz]';
                case 'Bubble Length'
                    vary = 'lb';
                    varx = 'jgj';
                    xlab = 'J_G/J [-]';
                    ylab = 'L_B/D [-]';
                case 'Bubble Void Fraction'
                    vary = 'rgb';
                    varx = 'jgj';
                    xlab = 'J_G/J [-]';
                    ylab = 'R_{GB} [-]';
                case 'Liquid Slug Holdup'
                    vary = 'rls';
                    varx = 'j';
                    xlab = 'J [m/s]';
                    ylab = 'R_{LS} [-]';
                case 'Intermittence Factor'
                    vary = 'beta';
                    varx = 'jgj';
                    xlab = 'J_G/J [-]';
                    ylab = '\beta [-]';
            end
            
            [xData, yData] = prepareCurveData(app.euler.(varx), app.euler.(vary).(stat));
            switch ftype
                case 'No fit'
                    ft = [];
                case 'Linear'
                    ft = fittype('poly1');
                case 'Quadratic'
                    ft = fittype('poly2');
                case 'Power'
                    ft = fittype('power1');
                case 'Power + C'
                    ft = fittype('power2');
                case 'Exponential'
                    ft = fittype('exp1');
                case 'Custom'
                    ft = [];
            end
            
            xplot = sort(xData);
            if ~isempty(ft)
                [fr, gof] = fit(xData, yData, ft);
                title(app.fig_Euler_4, ['R^2 = ', num2str(gof.rsquare)])
                f = plot(app.fig_Euler_4, xplot, fr(xplot), 'r-', app.euler.(varx), app.euler.(vary).(stat), 'k.');
                f(1).LineWidth = 3;
                for i = 2 : numel(f)
                    f(i).MarkerSize = 15;
                end
                
                xlabel(app.fig_Euler_4, xlab);
                ylabel(app.fig_Euler_4, ylab);
            else
                plot(app.fig_Euler_4, app.euler.(varx), app.euler.(vary).(stat), 'k.', 'MarkerSize', 15)
                xlabel(app.fig_Euler_4, xlab);
                ylabel(app.fig_Euler_4, ylab);
                title(app.fig_Euler_4, '');
            end
            
        end
        function process_lagrange(app, pt, st)
            st = sort(st);
            sta = st{1};
            stb = st{2};
            
            fs = app.lfile.data.(sta).Geometry.Frequency;
            
            ts_a1 = app.lfile.data.(sta).(pt).TS1;
            
            ts_b1 = app.lfile.data.(stb).(pt).TS1;
            
            D_a = app.lfile.data.(sta).Geometry.Diameter;
            D_b = app.lfile.data.(stb).Geometry.Diameter;
            
            jl_a1 = mean(app.lfile.data.(sta).(pt).jl);
            jg_a1 = mean(app.lfile.data.(sta).(pt).jg);
            
            jl_b1 = mean(app.lfile.data.(stb).(pt).jl);
            jg_b1 = mean(app.lfile.data.(stb).(pt).jg);
            
            theta_a = app.lfile.data.(sta).Geometry.Angle;
            theta_b = app.lfile.data.(stb).Geometry.Angle;
            
            dt = finddelay(ts_a1, ts_b1);
            
            ts_a1(end - dt : end) = [];
            
            ts_b1(1 : dt + 1) = [];
            
            idx_a1 = ucdet(app, ts_a1, D_a, jl_a1, jg_a1, fs, theta_a);
            idx_b1 = ucdet(app, ts_b1, D_b, jl_b1, jg_b1, fs, theta_b);
            
            freq_a = frequency(app, idx_a1, ts_a1, fs);
            freq_b = frequency(app, idx_b1, ts_b1, fs);
            
            v_a = velocity_mc(app, idx_a1, ts_a1, jl_a1, jg_a1);
            v_b = velocity_mc(app, idx_b1, ts_b1, jl_b1, jg_b1);
            
            [Lb_a, Ls_a, RGb_a, RGs_a] = hparam(app, idx_a1, v_a, ts_a1, fs);
            [Lb_b, Ls_b, RGb_b, RGs_b] = hparam(app, idx_b1, v_b, ts_b1, fs);
            
            [corresp, ~, coal_idx] = coalescence(app, ts_a1, ts_b1, idx_a1, idx_b1);
            
            coal_idx = [coal_idx - 1, coal_idx];
            
            coal2 = sort(coal_idx(1:end));
            coal2 = coal2';
            
            regular_idx = setdiff(1:numel(idx_a1(:,1)), coal2);
            regular_idx = regular_idx';
            
            reg2 = corresp(regular_idx);
            
            vB_a_r = v_a(regular_idx);
            vB_b_r = v_b(reg2);
            
            lb_a_r = Lb_a(regular_idx);
            lb_b_r = Lb_b(reg2);
            
            ls_a_r = Ls_a(regular_idx);
            ls_b_r = Ls_b(reg2);
            
            rgb_a_r = RGb_a(regular_idx);
            rgb_b_r = RGb_b(reg2);
            
            rgs_a_r = RGs_a(regular_idx);
            rgs_b_r = RGs_b(reg2);
            
            freq_a_r = freq_a(regular_idx);
            freq_b_r = freq_b(reg2);
            
            beta_a_r = lb_a_r./(ls_a_r + lb_a_r);
            beta_b_r = lb_b_r./(ls_b_r + lb_b_r);
            
            abs_dif_vB = (vB_b_r - vB_a_r);
            abs_dif_lb = (lb_b_r - lb_a_r);
            abs_dif_ls = (ls_b_r - ls_a_r);
            abs_dif_rgb = (rgb_b_r - rgb_a_r);
            abs_dif_rgs = (rgs_b_r - rgs_a_r);
            abs_dif_freq = (freq_b_r - freq_a_r);
            abs_dif_beta = (beta_b_r - beta_a_r);
            
            abs_dif_vB(end) = [];
            abs_dif_lb(end) = [];
            abs_dif_ls(end) = [];
            abs_dif_rgb(end) = [];
            abs_dif_rgs(end) = [];
            abs_dif_freq(end) = [];
            abs_dif_beta(end) = [];
            
            pct_dif_vB = 100*(vB_b_r - vB_a_r)./(vB_a_r);
            pct_dif_lb = 100*(lb_b_r - lb_a_r)./(lb_a_r);
            pct_dif_ls = 100*(ls_b_r - ls_a_r)./(ls_a_r);
            pct_dif_rgb = 100*(rgb_b_r - rgb_a_r)./(rgb_a_r);
            pct_dif_rgs = 100*(rgs_b_r - rgs_a_r)./(rgs_a_r);
            pct_dif_freq = 100*(freq_b_r - freq_a_r)./(freq_a_r);
            pct_dif_beta = 100*(beta_b_r - beta_a_r)./(beta_a_r);
            
            pct_dif_vB(end) = [];
            pct_dif_lb(end) = [];
            pct_dif_ls(end) = [];
            pct_dif_rgb(end) = [];
            pct_dif_rgs(end) = [];
            pct_dif_freq(end) = [];
            pct_dif_beta(end) = [];
            
            app.lagrange.VB.abs = abs_dif_vB;
            app.lagrange.VB.pct = pct_dif_vB;
            app.lagrange.lb.abs = abs_dif_lb;
            app.lagrange.lb.pct = pct_dif_lb;
            app.lagrange.ls.abs = abs_dif_ls;
            app.lagrange.ls.pct = pct_dif_ls;
            app.lagrange.rgb.abs = abs_dif_rgb;
            app.lagrange.rgb.pct = pct_dif_rgb;
            app.lagrange.rgs.abs = abs_dif_rgs;
            app.lagrange.rgs.pct = pct_dif_rgs;
            app.lagrange.freq.abs = abs_dif_freq;
            app.lagrange.freq.pct = pct_dif_freq;
            app.lagrange.beta.abs = abs_dif_beta;
            app.lagrange.beta.pct = pct_dif_beta;
            
        end
        function [corresp, regular_idx, coal_idx] = coalescence(app, ts_a1, ts_b1, idx_a1, idx_b1)
            
            corresp = zeros(size(idx_a1(:,1)));
            % loc_end_1a = zeros(size(idx_a1(:,2)));
            
            istart1 = idx_a1(:,1);
            iend1 = idx_a1(:,2);
            istart2 = idx_b1(:,1);
            iend2 = idx_b1(:,2);
            
            % Find the closest value in signal A
            for i = 1 : numel(idx_a1(:,1))
                [~, corresp(i)] = min(abs(idx_a1(i,1) - idx_b1(:,1)));
                %     [~, loc_end_1a(i)] = min(abs(idx_a1(i,2) - idx_b1(:,2)));
            end
            
            % Find repeated values (coalescence)
            % coal_idx = find(diff(loc_str_1a) == 0);
            coal_idx = finddup(app, corresp);
            
            % % Check for gaps
            % find(diff(corresp) > 1)
            
            % Exclude first bubble if it is a coalescence
            % Fix this later
            if ~isempty(coal_idx) && coal_idx(1) == 2
                coal_idx(1) = [];
            end
            
            % nc = numel(coal_idx);
            del_coal = zeros(size(coal_idx));
            n = numel(coal_idx);
            for i = 1 : n
                
                
                if coal_idx(i) == numel(corresp) - 1
                    continue
                end
                
                % Set sample bubbles: n-1 (m - minus), n and n+1 (p - plus)
                
                if coal_idx(i) ~= numel(istart1)
                    ts1_np = ts_a1(istart1(coal_idx(i)) : iend1(coal_idx(i) + 1));
                    ts1_p = ts_a1(istart1(coal_idx(i) + 1) : iend1(coal_idx(i) + 1));
                    
                    ts2_p = ts_b1(istart2(corresp(coal_idx(i)) + 1) : iend2(corresp(coal_idx(i)) + 1));
                    
                    r0_p = max(xcorr(ts2_p));
                    
                    r_p_p = max(xcorr(ts1_p, ts2_p));
                    r_np_p = max(xcorr(ts1_np, ts2_p));
                    
                    % Bubble (np1) and (p2)
                    rx_np_p = abs(1 - r_np_p/r0_p);
                    
                    % Bubble (p1) and (p2)
                    rx_p_p = abs(1 - r_p_p/r0_p);
                end
                
                %     ts1_l = ts_a1(istart1(coal_idx(i) - 2) : iend1(coal_idx(i) - 2));
                ts1_m = ts_a1(istart1(coal_idx(i) - 1) : iend1(coal_idx(i) - 1));
                ts1_n = ts_a1(istart1(coal_idx(i)) : iend1(coal_idx(i)));
                ts1_lm = ts_a1(istart1(coal_idx(i) - 2) : iend1(coal_idx(i) - 1));
                ts1_mn = ts_a1(istart1(coal_idx(i) - 1) : iend1(coal_idx(i)));
                
                ts2_m = ts_b1(istart2(corresp(coal_idx(i)) - 1) : iend2(corresp(coal_idx(i)) - 1));
                ts2_n = ts_b1(istart2(corresp(coal_idx(i))) : iend2(corresp(coal_idx(i))));
                
                r0_n = max(xcorr(ts2_n));
                r0_m = max(xcorr(ts2_m));
                
                r_lm_m = max(xcorr(ts1_lm, ts2_m));
                r_m_n = max(xcorr(ts1_m, ts2_n));
                r_n_n = max(xcorr(ts1_n, ts2_n));
                r_mn_n = max(xcorr(ts1_mn, ts2_n));
                
                rx_lm_m = abs(1 - r_lm_m/r0_m);
                % Bubble (m1) and (n2)
                rx_m_n = abs(1 - r_m_n/r0_n);
                rx_n_n = abs(1 - r_n_n/r0_n);
                
                % Bubble (mn1) and (n2)
                rx_mn_n = abs(1 - r_mn_n/r0_n);
                
                if rx_m_n < rx_mn_n && rx_np_p < rx_p_p
                    corresp(coal_idx(i)) = corresp(coal_idx(i)) + 1;
                    coal_idx(i) = coal_idx(i) + 1;
                    %         disp('Fixed coal +1')
                    %         disp(coal_idx(i))
                end
                
                if rx_lm_m > rx_n_n
                    %         corresp(coal_idx(i)) = corresp(coal_idx(i)) - 1;
                    corresp(coal_idx(i) - 1) = corresp(coal_idx(i) - 1) - 1;
                    coal_idx(i) = coal_idx(i) - 1;
                    %         disp('Fixed coal -1')
                    %         disp(coal_idx(i))
                end
                
            end
            % find(diff(corresp) > 1)
            del_coal(del_coal == 0) = [];
            coal_idx(del_coal) = [];
            % regular_idx = setdiff(unique(corresp), coal_idx);
            regular_idx = setdiff(1:numel(istart1), coal_idx)';
            
            
            % Check
            while regular_idx(end) >= numel(idx_a1(:,1))
                regular_idx(end) = [];
            end
        end
        
        function dup = finddup(~, data)
            [~, rind] = unique(data);
            dup = setdiff(1:numel(data), rind)';
        end
        function plotlagrange(app)
            selectedVar = app.VariablesListBox_2.Value;
            switch selectedVar
                case 'Velocity'
                    var = 'VB';
                    ylab = 'V_{TB} percent difference [%]';
                    ttl = 'Unit-cell velocity percent difference';
                case 'Frequency'
                    var = 'freq';
                    ylab = 'Frequency percent difference [%]';
                    ttl = 'Unit-cell frequency percent difference';
                case 'Bubble Length'
                    var = 'lb';
                    ylab = 'L_B/D percent difference [%]';
                    ttl = 'Bubble length percent difference';
                case 'Bubble Void Fraction'
                    var = 'rgb';
                    ylab = 'R_{GB} percent difference [%]';
                    ttl = 'Bubble void fraction percent difference';
                case 'Liquid Slug Holdup'
                    var = 'rgs';
                    ylab = 'R_{GS} percent difference [%]';
                    ttl = 'Slug void fraction percent difference';
                case 'Intermittence Factor'
                    var = 'beta';
                    ylab = '\beta percent difference [%]';
                    ttl = 'Intermittence factor percent difference';
            end
            
            res = app.lagrange.(var).pct;
            idx_neg = find(res < 0);
            idx_nul = find(res == 0);
            idx_pos = find(res > 0);
            res_neg = res(res < 0);
            res_nul = res(res == 0);
            res_pos = res(res > 0);
            
            plot(app.fig_Euler_2, idx_neg, res_neg, 'rx', idx_nul, res_nul, 'bx', idx_pos, res_pos, 'gx')
            xlabel(app.fig_Euler_2, 'Unit cell index [-]')
            ylabel(app.fig_Euler_2, ylab)
            title(app.fig_Euler_2, ttl);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotflowmap(app, 0, 25.4e-3, 1000, 1.23, 0, 0)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ReadyLamp.Color = [1 1 0];
            app.ReadyLampLabel.Text = 'Waiting for file input';
            [file, path] = uigetfile('*.mat');
            ff = fullfile(path, file);
            figure(app.ELaStAnEUIFigure)
            if file ~= 0
                app.ReadyLampLabel.Text = 'Processing file';
                app.CurrentfileEditField.Value = ff;
                app.lfile = load(ff);
                fn = fieldnames(app.lfile.data);
                for i = 1 : numel(fn)
                    uitreenode(app.Tree, 'Text', ['Station', num2str(i)]);
                end
                
                fn = fieldnames(app.lfile.data.Station1);
                for i = 1 : numel(app.Tree.Children)
                    for j = 1 : numel(fn) - 1
                        uitreenode(app.Tree.Children(i), 'Text', ['P', num2str(j)]);
                    end
                end
                
                fn = fieldnames(app.lfile.data);
                points = fieldnames(app.lfile.data.Station1);
                points(1) = [];
                app.ExperimentalpointListBox_2.Items = points;
                app.ExpPoints_hist.Items = points;
                app.Station_hist.Items = fn;
                app.StationsListBox.Items = fn;
                
                app.ExpPoints_hist.Value = points(1);
                app.Station_hist.Value = fn(1);
                app.ExperimentalpointListBox_2.Value = points(1);
                app.StationsListBox.Value = fn(1:2);
                
                app.ReadyLampLabel.Text = 'Processing data';
                
                app.result = dataprocess(app, fn, points);
                
                vb_mean = zeros(numel(points), numel(fn));
                lb_mean = zeros(numel(points), numel(fn));
                rgb_mean = zeros(numel(points), numel(fn));
                rls_mean = zeros(numel(points), numel(fn));
                freq_mean = zeros(numel(points), numel(fn));
                beta_mean = zeros(numel(points), numel(fn));
                
                vb_std = zeros(numel(points), numel(fn));
                lb_std = zeros(numel(points), numel(fn));
                rgb_std = zeros(numel(points), numel(fn));
                rls_std = zeros(numel(points), numel(fn));
                freq_std = zeros(numel(points), numel(fn));
                beta_std = zeros(numel(points), numel(fn));
                
                jgj = zeros(numel(points), numel(fn));
                jlj = zeros(numel(points), numel(fn));
                jm = zeros(numel(points), numel(fn));
                jgjl = zeros(numel(points), numel(fn));
                jljg = zeros(numel(points), numel(fn));
                
                for i = 1 : numel(points)
                    for j = 1 : numel(fn)
                        vb_mean(i,j) = mean(app.result.(points{i}).(fn{j}).VB);
                        lb_mean(i,j) = mean(app.result.(points{i}).(fn{j}).lb);
                        rgb_mean(i,j) = mean(app.result.(points{i}).(fn{j}).rgb);
                        rls_mean(i,j) = mean(app.result.(points{i}).(fn{j}).rls);
                        freq_mean(i,j) = harmmean(app.result.(points{i}).(fn{j}).freq);
                        beta_mean(i,j) = mean(app.result.(points{i}).(fn{j}).beta);
                        
                        vb_std(i,j) = std(app.result.(points{i}).(fn{j}).VB);
                        lb_std(i,j) = std(app.result.(points{i}).(fn{j}).lb);
                        rgb_std(i,j) = std(app.result.(points{i}).(fn{j}).rgb);
                        rls_std(i,j) = std(app.result.(points{i}).(fn{j}).rls);
                        freq_std(i,j) = std(app.result.(points{i}).(fn{j}).freq);
                        beta_std(i,j) = std(app.result.(points{i}).(fn{j}).beta);
                        
                        jg = mean(app.lfile.data.(fn{j}).(points{i}).jg);
                        jl = mean(app.lfile.data.(fn{j}).(points{i}).jl);
                        jm(i,j) = jg + jl;
                        jgj(i,j) = jg/jm(i,j);
                        jlj(i,j) = jl/jm(i,j);
                        jgjl(i,j) = jg/jl;
                        jljg(i,j) = jl/jg;
                    end
                end
                
                app.euler.VB.mean = vb_mean;
                app.euler.VB.std = vb_std;
                app.euler.freq.mean = freq_mean;
                app.euler.freq.std = freq_std;
                app.euler.rgb.mean = rgb_mean;
                app.euler.rgb.std = rgb_std;
                app.euler.rls.mean = rls_mean;
                app.euler.rls.std = rls_std;
                app.euler.lb.mean = lb_mean;
                app.euler.lb.std = lb_std;
                app.euler.beta.mean = beta_mean;
                app.euler.beta.std = beta_std;
                app.euler.j = jm;
                app.euler.jgj = jgj;
                app.euler.jlj = jlj;
                app.euler.jgjl = jgjl;
                app.euler.jljg = jljg;
                
                plothistogram(app);
                ploteuler(app);
                pt = app.ExperimentalpointListBox_2.Value;
                st = app.StationsListBox.Value;
                if numel(st) == 2
                    process_lagrange(app, pt, st);
                end
                plotlagrange(app)
                
            end
            app.ReadyLamp.Color = [0 1 0];
            app.ReadyLampLabel.Text = 'Ready';
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if isempty(selectedNodes.Children)
                cst = selectedNodes.Parent.Text;
                cpt = selectedNodes.Text;
                geom = app.lfile.data.(cst).Geometry;
                pt = app.lfile.data.(cst).(cpt);
                D = geom.Diameter;
                jl = mean(pt.jl);
                jg = mean(pt.jg);
                rho_L = 1e3*mean(pt.Density);
                rho_G = 1.23;
                theta = geom.Angle;
                T = mean(pt.Temperature);
                P = mean(pt.Pressure);
                Patm = mean(pt.AtmPressure);
                histogram(app.UIAxes, app.lfile.data.(cst).(cpt).TS1)
                plotflowmap(app, theta, D, rho_L, rho_G, jl, jg)
                app.JLEditField.Value = jl;
                app.JGEditField.Value = jg;
                app.LiquiddensityEditField.Value = rho_L;
                app.PressureEditField.Value = P;
                app.AtmPressureEditField.Value = Patm;
                app.TemperatureEditField.Value = T;
            end
        end

        % Callback function
        function ExperimentalpointListBox_3ValueChanged(app, event)
            plothistogram(app);
        end

        % Value changed function: VariablesListBox_3
        function VariablesListBox_3ValueChanged(app, event)
            plothistogram(app);
        end

        % Value changed function: Station_hist
        function Station_histValueChanged(app, event)
            plothistogram(app);
        end

        % Value changed function: ExpPoints_hist
        function ExpPoints_histValueChanged(app, event)
            plothistogram(app)
        end

        % Value changed function: ExperimentalpointListBox_2
        function ExperimentalpointListBox_2ValueChanged(app, event)
            st = app.StationsListBox.Value;
            while numel(st) > 2
                st(end) = [];
            end
            app.StationsListBox.Value = st;
            app.ReadyLamp.Color = [1 1 0];
            app.ReadyLampLabel.Text = 'Processing data';
            pt = app.ExperimentalpointListBox_2.Value;
            st = app.StationsListBox.Value;
            if numel(st) == 2
                process_lagrange(app, pt, st);
            end
            plotlagrange(app)
            app.ReadyLamp.Color = [0 1 0];
            app.ReadyLampLabel.Text = 'Ready';
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
            ploteuler(app);
        end

        % Selection changed function: StatisticButtonGroup
        function StatisticButtonGroupSelectionChanged(app, event)
            ploteuler(app);
        end

        % Value changed function: FittypeListBox
        function FittypeListBoxValueChanged(app, event)
            ploteuler(app);
        end

        % Value changed function: StationsListBox
        function StationsListBoxValueChanged(app, event)
            st = app.StationsListBox.Value;
            while numel(st) > 2
                st(end) = [];
            end
            app.StationsListBox.Value = st;
            pt = app.ExperimentalpointListBox_2.Value;
            st = app.StationsListBox.Value;
            app.ReadyLamp.Color = [1 1 0];
            app.ReadyLampLabel.Text = 'Processing data';
            if numel(st) == 2
                process_lagrange(app, pt, st);
            end
            plotlagrange(app);
            app.ReadyLamp.Color = [0 1 0];
            app.ReadyLampLabel.Text = 'Ready';
        end

        % Value changed function: VariablesListBox_2
        function VariablesListBox_2ValueChanged(app, event)
            plotlagrange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ELaStAnEUIFigure = uifigure('Visible', 'off');
            app.ELaStAnEUIFigure.Name = 'ELaStAnE';
            app.TabGroup = uitabgroup(app.ELaStAnEUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LoadButton = uibutton(app.DataTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CurrentfileEditFieldLabel = uilabel(app.DataTab);
            app.CurrentfileEditFieldLabel.Text = 'Current file';
            app.CurrentfileEditField = uieditfield(app.DataTab, 'text');
            app.CurrentfileEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.DataTab);
            title(app.UIAxes, 'Void fraction histogram')
            xlabel(app.UIAxes, 'R_{GB} [-]')
            ylabel(app.UIAxes, 'PDF [-]')
            app.ExperimentaldataPanel = uipanel(app.DataTab);
            app.ExperimentaldataPanel.Title = 'Experimental data';
            app.JLEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.JLEditFieldLabel.Text = 'JL';
            app.JLEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.JLEditField.ValueDisplayFormat = '%11.2g';
            app.JLEditField.Editable = 'off';
            app.JGEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.JGEditFieldLabel.Text = 'JG';
            app.JGEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.JGEditField.ValueDisplayFormat = '%11.2g';
            app.JGEditField.Editable = 'off';
            app.LiquiddensityEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.LiquiddensityEditFieldLabel.Text = 'Liquid density';
            app.LiquiddensityEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.LiquiddensityEditField.ValueDisplayFormat = '%11.2g';
            app.LiquiddensityEditField.Editable = 'off';
            app.TemperatureEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.2g';
            app.TemperatureEditField.Editable = 'off';
            app.PressureEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.PressureEditField.ValueDisplayFormat = '%11.2g';
            app.PressureEditField.Editable = 'off';
            app.AtmPressureEditFieldLabel = uilabel(app.ExperimentaldataPanel);
            app.AtmPressureEditFieldLabel.Text = 'Atm Pressure';
            app.AtmPressureEditField = uieditfield(app.ExperimentaldataPanel, 'numeric');
            app.AtmPressureEditField.ValueDisplayFormat = '%11.2g';
            app.AtmPressureEditField.Editable = 'off';
            app.UIAxes_2 = uiaxes(app.DataTab);
            title(app.UIAxes_2, 'Flow map')
            xlabel(app.UIAxes_2, 'J_G [m/s]')
            ylabel(app.UIAxes_2, 'J_L [m/s]')
            app.Tree = uitree(app.DataTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.fig_Euler_3 = uiaxes(app.HistogramsTab);
            title(app.fig_Euler_3, 'Title')
            xlabel(app.fig_Euler_3, '')
            ylabel(app.fig_Euler_3, 'PDF [-]')
            app.VariablesListBox_3Label = uilabel(app.HistogramsTab);
            app.VariablesListBox_3Label.Text = 'Variables';
            app.VariablesListBox_3 = uilistbox(app.HistogramsTab);
            app.VariablesListBox_3.Items = {'Velocity', 'Frequency', 'Bubble Length', 'Bubble Void Fraction', 'Liquid Slug Holdup', 'Intermittence Factor'};
            app.VariablesListBox_3.ValueChangedFcn = createCallbackFcn(app, @VariablesListBox_3ValueChanged, true);
            app.VariablesListBox_3.Value = 'Velocity';
            app.ExperimentalpointListBox_3Label = uilabel(app.HistogramsTab);
            app.ExperimentalpointListBox_3Label.Text = 'Experimental point';
            app.ExpPoints_hist = uilistbox(app.HistogramsTab);
            app.ExpPoints_hist.Items = {};
            app.ExpPoints_hist.ValueChangedFcn = createCallbackFcn(app, @ExpPoints_histValueChanged, true);
            app.ExpPoints_hist.Value = {};
            app.StationListBoxLabel = uilabel(app.HistogramsTab);
            app.StationListBoxLabel.Text = 'Station';
            app.Station_hist = uilistbox(app.HistogramsTab);
            app.Station_hist.Items = {};
            app.Station_hist.Multiselect = 'on';
            app.Station_hist.ValueChangedFcn = createCallbackFcn(app, @Station_histValueChanged, true);
            app.Station_hist.Value = {};
            app.EulerTab = uitab(app.TabGroup);
            app.EulerTab.Title = 'Euler';
            app.VariablesListBoxLabel = uilabel(app.EulerTab);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.EulerTab);
            app.VariablesListBox.Items = {'Velocity', 'Frequency', 'Bubble Length', 'Bubble Void Fraction', 'Liquid Slug Holdup', 'Intermittence Factor'};
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = 'Velocity';
            app.fig_Euler_4 = uiaxes(app.EulerTab);
            title(app.fig_Euler_4, 'Title')
            xlabel(app.fig_Euler_4, '')
            ylabel(app.fig_Euler_4, 'Y')
            app.StatisticButtonGroup = uibuttongroup(app.EulerTab);
            app.StatisticButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StatisticButtonGroupSelectionChanged, true);
            app.StatisticButtonGroup.Title = 'Statistic';
            app.AverageButton = uiradiobutton(app.StatisticButtonGroup);
            app.AverageButton.Text = 'Average';
            app.AverageButton.Value = true;
            app.StdDeviationButton = uiradiobutton(app.StatisticButtonGroup);
            app.StdDeviationButton.Text = 'Std. Deviation';
            app.FittypeListBoxLabel = uilabel(app.EulerTab);
            app.FittypeListBoxLabel.Text = 'Fit type';
            app.FittypeListBox = uilistbox(app.EulerTab);
            app.FittypeListBox.Items = {'No fit', 'Linear', 'Quadratic', 'Power', 'Power + C', 'Exponential', 'Custom'};
            app.FittypeListBox.ValueChangedFcn = createCallbackFcn(app, @FittypeListBoxValueChanged, true);
            app.FittypeListBox.Value = 'No fit';
            app.CustomfittypeEditFieldLabel = uilabel(app.EulerTab);
            app.CustomfittypeEditFieldLabel.Enable = 'off';
            app.CustomfittypeEditFieldLabel.Text = 'Custom fit type';
            app.CustomfittypeEditField = uieditfield(app.EulerTab, 'text');
            app.CustomfittypeEditField.Enable = 'off';
            app.LagrangeTab = uitab(app.TabGroup);
            app.LagrangeTab.Title = 'Lagrange';
            app.fig_Euler_2 = uiaxes(app.LagrangeTab);
            title(app.fig_Euler_2, 'Title')
            xlabel(app.fig_Euler_2, '')
            ylabel(app.fig_Euler_2, 'Y')
            app.VariablesListBox_2Label = uilabel(app.LagrangeTab);
            app.VariablesListBox_2Label.Text = 'Variables';
            app.VariablesListBox_2 = uilistbox(app.LagrangeTab);
            app.VariablesListBox_2.Items = {'Velocity', 'Frequency', 'Bubble Length', 'Bubble Void Fraction', 'Liquid Slug Holdup', 'Intermittence Factor'};
            app.VariablesListBox_2.ValueChangedFcn = createCallbackFcn(app, @VariablesListBox_2ValueChanged, true);
            app.VariablesListBox_2.Value = 'Velocity';
            app.ExperimentalpointListBox_2Label = uilabel(app.LagrangeTab);
            app.ExperimentalpointListBox_2Label.Text = 'Experimental point';
            app.ExperimentalpointListBox_2 = uilistbox(app.LagrangeTab);
            app.ExperimentalpointListBox_2.Items = {};
            app.ExperimentalpointListBox_2.ValueChangedFcn = createCallbackFcn(app, @ExperimentalpointListBox_2ValueChanged, true);
            app.ExperimentalpointListBox_2.Value = {};
            app.StationsListBoxLabel = uilabel(app.LagrangeTab);
            app.StationsListBoxLabel.Text = 'Stations';
            app.StationsListBox = uilistbox(app.LagrangeTab);
            app.StationsListBox.Items = {};
            app.StationsListBox.Multiselect = 'on';
            app.StationsListBox.ValueChangedFcn = createCallbackFcn(app, @StationsListBoxValueChanged, true);
            app.StationsListBox.Value = {};
            app.ReadyLampLabel = uilabel(app.ELaStAnEUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.ELaStAnEUIFigure);
            app.ELaStAnEbyConradoChiarello2020Label = uilabel(app.ELaStAnEUIFigure);
            app.ELaStAnEbyConradoChiarello2020Label.Text = 'ELaStAnE by Conrado Chiarello, 2020';
            % Show the figure after all components are created
            app.ELaStAnEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elastane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ELaStAnEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ELaStAnEUIFigure)
        end
    end
end